classdef LF_hearing_DataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        LFHearingDataViewerPanel    matlab.ui.container.Panel
        SelectDataFolderButton      matlab.ui.control.Button
        FolderListBox               matlab.ui.control.ListBox
        FolderListBoxLabel          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        EstimatesTab                matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        UIAxesSTR                   matlab.ui.control.UIAxes
        UIAxesHTR                   matlab.ui.control.UIAxes
        UIAxesSTL                   matlab.ui.control.UIAxes
        UIAxesHTL                   matlab.ui.control.UIAxes
        IndividualpresentationsTab  matlab.ui.container.Tab
        GridLayout2_2               matlab.ui.container.GridLayout
        UIAxesSTR_2                 matlab.ui.control.UIAxes
        UIAxesHTR_2                 matlab.ui.control.UIAxes
        UIAxesSTL_2                 matlab.ui.control.UIAxes
        UIAxesHTL_2                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        DataPath % Path to data files
        Coll % color vector for plotting
        STMTdata = {}% Table for STMT data
        THRSdata = {}% table for Threshold data
        EarphoneSens % Description
        MainApp % Main figure handle
        
    end
    
    methods (Access = private)
        
        function results = loaddata(app,FileNames)
            wb = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message","Reading data...");
            app.EarphoneSens = app.MainApp.EarphoneSens;
            app.THRSdata = {};
            app.STMTdata = {};
            for i = 1:length(FileNames)
                FileIO = matfile([app.DataPath,FileNames{i}],"Writable",false);
                Tests = fieldnames(FileIO);
                if ~isempty(who(FileIO,'Patient'))
                    Patient = FileIO.Patient;
                    Name = regexp(Patient.ID,'_','split');
                    if length(Name) == 2
                        Patient.ID = Name{1};
                        Patient.Age = Name{2}(1:2);
                        Patient.Gender = Name{2}(end);
                        Patient.Type = Name{1}(1:2);
                    end
                else
                    Name = regexp(FileNames{i},'_','split');
                    Patient.ID = Name{1};
                end
                
                s = Tests(contains(Tests,'STMT_'));
                t = Tests(contains(Tests,'Thres_'));
                tmp = {};
                if ~isempty(s)
                    for j = 1:length(s)
                        stmt = FileIO.(s{j});
                        [Est, STD] = ...
                            maxlike(mean(stmt.Resp.Level),std(stmt.Resp.Level),...
                            stmt.Resp.Level,stmt.Resp.Correct);
                        tmp{j,1} = Patient.ID;
                        if isfield(stmt,'Ear')
                            tmp{j,2} = stmt.Ear;
                        else
                            tmp{j,2} = 'Left';
                        end
                        tmp{j,3} = stmt.StimParam(1);
                        tmp{j,4} = Est;
                        tmp{j,5} = STD;
                        tmp{j,6} = stmt.StimParam(3);
                        tmp{j,7} = stmt.StimParam(4);
                        if length(stmt.StimParam)>4
                            tmp{j,8} = stmt.StimParam(5);
                        else
                            tmp{j,8} = 40; % default presentation level 40 dBSL
                        end
                    end
                    app.STMTdata = [app.STMTdata; tmp];
                    
                end
                
                tmp_ = {};
                if ~isempty(t)
                    for k = 1:length(t)
                        thres = FileIO.(t{k});
                        tmp_1 = {};
                        for l = 1:size(thres.Raw,3)
                            tmp_1{l,1} = Patient.ID;
                            if size(thres.Raw,1)>=6 && sum(thres.Raw(:,2))>=3
                                [Est, STD] = ...
                                    maxlike(mean(thres.Raw(~thres.Raw(:,4,l),1,l)),...
                                    std(thres.Raw(~thres.Raw(:,4,l),1,l)),...
                                    thres.Raw(~thres.Raw(:,4,l),1,l),...
                                    thres.Raw(~thres.Raw(:,4,l),2,l));
                            
                            else
                                Est = NaN;
                                STD = NaN;
                            end
                            if isfield(thres,'Ear')
                                tmp_1{l,2} = {thres.Ear};
                            else
                                tmp_1{l,2} = 'Left';
                            end
                            tmp_1{l,3} = thres.Estimate.Freqs(l);
                            tmp_1{l,4} = Est;
                            tmp_1{l,5} = STD;
                            if sum(contains(fieldnames(thres.Estimate),'Thres_dBSPL'))
                                tmp_1{l,6} = thres.Estimate.Thres_dBSPL(l);
                            else
                                tmp_1{l,6} = thres.Estimate.Thres(l) + ...
                                    app.EarphoneSens.LdB(1,app.EarphoneSens.Frequencies == thres.Estimate.Freqs(1),1);
                            end
                        end
                        tmp_ = [tmp_; tmp_1];
                    end
                app.THRSdata = [app.THRSdata; tmp_];
                
                end
                clear FileIO
                
            end
            close(wb)
            if ~isempty(app.THRSdata)
                app.THRSdata = cell2table(app.THRSdata);
                app.THRSdata.Properties.VariableNames = {'ID','Ear','Frequency','Estimate','STD','Est_dBSPL'};
                app.THRSdata.ID = categorical(app.THRSdata.ID);
                app.THRSdata.Ear = categorical(app.THRSdata.Ear);
            end
            if ~isempty(app.STMTdata)
                app.STMTdata = cell2table(app.STMTdata);
                app.STMTdata.Properties.VariableNames = {'ID','Ear','Frequency','Estimate','STD','FreMod','TimeMod','SensLev'};
                app.STMTdata.ID = categorical(app.STMTdata.ID);
                app.STMTdata.Ear = categorical(app.STMTdata.Ear);
            end    


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            
            app.MainApp = MainApp;
            
            
        end

        % Value changed function: FolderListBox
        function FolderListBoxValueChanged(app, event)
            value = app.FolderListBox.Value;
            if iscell(value)
                loaddata(app,value)
            else
                loaddata(app,{value})
            end
           
            if strcmp(app.TabGroup.SelectedTab.Tag,'IndividualPres')
                PlotThreshold(app,{value})
            else  
                app.Coll = colormap(app.UIAxesHTL);
                app.Coll = app.Coll(floor(linspace(1,size(app.Coll,1)-30,length(value))),:);
                cla(app.UIAxesHTL)
                cla(app.UIAxesSTL)
                cla(app.UIAxesHTR)
                cla(app.UIAxesSTR)
                %make background for threshold plot. 
                fr = app.EarphoneSens.Frequencies;
                LoudLevs = iso226(fr,[0 50 100],'LL');
                patch(app.UIAxesHTL,...
                    [fr fr(end:-1:1)],[LoudLevs(:,1)' LoudLevs(end:-1:1,2)'],...
                    [0.2 0.8 0.2],'FaceAlpha',0.2,'LineStyle','none');
                patch(app.UIAxesHTL,...
                    [fr fr(end:-1:1)],[LoudLevs(:,2)' LoudLevs(end:-1:1,3)'],...
                    [0.8 0.2 0.8],'FaceAlpha',0.2,'LineStyle','none');
                text(app.UIAxesHTL,190,LoudLevs(end-1,1),'MAF');
                text(app.UIAxesHTL,190,LoudLevs(end-1,2),'50 phon');
                text(app.UIAxesHTL,190,LoudLevs(end-1,3),'100 phon');
    
                patch(app.UIAxesHTR,...
                    [fr fr(end:-1:1)],[LoudLevs(:,1)' LoudLevs(end:-1:1,2)'],...
                    [0.2 0.8 0.2],'FaceAlpha',0.2,'LineStyle','none');
                patch(app.UIAxesHTR,...
                    [fr fr(end:-1:1)],[LoudLevs(:,2)' LoudLevs(end:-1:1,3)'],...
                    [0.8 0.2 0.8],'FaceAlpha',0.2,'LineStyle','none');
                text(app.UIAxesHTR,190,LoudLevs(end-1,1),'MAF');
                text(app.UIAxesHTR,190,LoudLevs(end-1,2),'50 phon');
                text(app.UIAxesHTR,190,LoudLevs(end-1,3),'100 phon');
    
    
                for i = 1:length(value)
                    IDS = regexp(value{i},'_','split');
                    ID{i} = IDS{1};
                    if ~isempty(app.THRSdata)
                        
                        Left_thres = app.THRSdata(app.THRSdata.ID == ID{i} & app.THRSdata.Ear == 'Left',:);    
                        Right_thres = app.THRSdata(app.THRSdata.ID == ID{i} & app.THRSdata.Ear == 'Right',:);
                        if sum(~isnan(Left_thres.Estimate))
                            EstL = rowfun(@mean,Left_thres,...
                                "InputVariables","Est_dBSPL",...
                                "GroupingVariables","Frequency",...
                                "OutputVariableNames",'Mean');
                            EstL_ = rowfun(@mean,Left_thres,...
                                "InputVariables","STD",...
                                "GroupingVariables","Frequency",...
                                "OutputVariableNames",'STD');
                            El(i) = errorbar(app.UIAxesHTL,EstL.Frequency,...
                                EstL.Mean, EstL_.STD,'o-', 'linewidth', 1,...
                                'MarkerSize', 8,'Color',app.Coll(i,:),...
                                'MarkerFaceColor',app.Coll(i,:));
                        else
                            uialert(app.UIFigure,'There is no valid threshold data for Left ear','','Icon','info')
                        end
                        if sum(~isnan(Right_thres.Estimate))
                        
                            EstR = rowfun(@mean,Right_thres,...
                                "InputVariables","Est_dBSPL",...
                                "GroupingVariables","Frequency",...
                                "OutputVariableNames",'Mean');
                            EstR_ = rowfun(@mean,Right_thres,...
                                "InputVariables","STD",...
                                "GroupingVariables","Frequency",...
                                "OutputVariableNames",'STD');
                            Er(i) = errorbar(app.UIAxesHTR,EstR.Frequency,...
                                 EstR.Mean, EstR_.STD,'s-', 'linewidth', 1,...
                                 'MarkerSize', 8,'Color',app.Coll(i,:),...
                                 'MarkerFaceColor',app.Coll(i,:));
                        else
                            uialert(app.UIFigure,'There is no valid threshold data for Right ear','','Icon','info')
                        end

                    else
                        uialert(app.UIFigure,'There is no valid threshold data','','Icon','info')
                    end
                    if ~isempty(app.STMTdata)
                        Left_stmt = app.STMTdata(app.STMTdata.ID == ID{i} & app.STMTdata.Ear == 'Left',:);
                        Left_stmt_LowMod = Left_stmt(Left_stmt.FreMod < 8,:);
                        Left_stmt_HighMod = Left_stmt(Left_stmt.FreMod >= 8,:);
                        Right_stmt = app.STMTdata(app.STMTdata.ID == ID{i} & app.STMTdata.Ear == 'Right',:); 
                        Right_stmt_LowMod = Right_stmt(Right_stmt.FreMod < 8,:);
                        Right_stmt_HighMod = Right_stmt(Right_stmt.FreMod >= 8,:);
                        EstL_lm = rowfun(@mean,Left_stmt_LowMod,...
                            "InputVariables","Estimate",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'Mean');
                        EstL_lm_ = rowfun(@mean,Left_stmt_LowMod,...
                            "InputVariables","STD",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'STD');
                        EstL_hm = rowfun(@mean,Left_stmt_HighMod,...
                            "InputVariables","Estimate",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'Mean');
                        EstL_hm_ = rowfun(@mean,Left_stmt_HighMod,...
                            "InputVariables","STD",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'STD');
                        EstR_lm = rowfun(@mean,Right_stmt_LowMod,...
                            "InputVariables","Estimate",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'Mean');
                        EstR_lm_ = rowfun(@mean,Right_stmt_LowMod,...
                            "InputVariables","STD",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'STD');
                        EstR_hm = rowfun(@mean,Right_stmt_HighMod,...
                            "InputVariables","Estimate",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'Mean');
                        EstR_hm_ = rowfun(@mean,Right_stmt_HighMod,...
                            "InputVariables","STD",...
                            "GroupingVariables","Frequency",...
                            "OutputVariableNames",'STD');

                        errorbar(app.UIAxesSTL,0.98*EstL_lm.Frequency,...
                             EstL_lm.Mean, EstL_lm_.STD,'v-', 'linewidth', 1,...
                             'MarkerSize', 10,'Color',app.Coll(i,:),...
                             'MarkerFaceColor',app.Coll(i,:));
                        errorbar(app.UIAxesSTL,1.02*EstL_hm.Frequency,...
                             EstL_hm.Mean, EstL_hm_.STD,'^-', 'linewidth', 1,...
                             'MarkerSize', 10,'Color',app.Coll(i,:),...
                             'MarkerFaceColor',app.Coll(i,:));
    
                        errorbar(app.UIAxesSTR,0.98*EstR_lm.Frequency,...
                             EstR_lm.Mean, EstR_lm_.STD,'V-', 'linewidth', 1,...
                             'MarkerSize', 10,'Color',app.Coll(i,:),...
                             'MarkerFaceColor',app.Coll(i,:));
                        errorbar(app.UIAxesSTR,1.02*EstR_hm.Frequency,...
                             EstR_hm.Mean, EstR_hm_.STD,'^-', 'linewidth', 1,...
                             'MarkerSize', 10,'Color',app.Coll(i,:),...
                             'MarkerFaceColor',app.Coll(i,:));
                        leghighlowmod(1) = plot(app.UIAxesSTR,0,0,'kV','linewidth',1,...
                            'MarkerSize', 10);
                        leghighlowmod(2) = plot(app.UIAxesSTR,0,0,'k^','linewidth',1,...
                            'MarkerSize', 10);

                    else
                        uialert(app.UIFigure,'There is no valid STM sensitivity data','','Icon','info')
                    end
                    
                    
                end
                if exist('El','var')
                    legend(app.UIAxesHTL,El,ID)
                elseif exist('Er','var')
                    legend(app.UIAxesHTL,Er,ID)
                end
                if exist('leghighlowmod','var')
                    legend(app.UIAxesSTR,leghighlowmod,{'Low modulation','High modulation'})
                end

            end
        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            Path2Files = uigetdir;
            app.DataPath = [Path2Files,'\'];
            Files = dir([app.DataPath,'*.mat']);
            if Path2Files ~= 0
                app.FolderListBox.Items = {Files.name};
                app.FolderListBoxLabel.Text = Path2Files;
            else
                msgbox('Did not get a valid folder... try again.')
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Tag, 'IndividualPres')
                app.FolderListBox.Multiselect = "off";
            else
                app.FolderListBox.Multiselect = "on";
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {840, 840};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.LFHearingDataViewerPanel = uipanel(app.GridLayout3);
            app.LFHearingDataViewerPanel.Title = 'LF-Hearing Data Viewer';
            app.FolderListBoxLabel = uilabel(app.LFHearingDataViewerPanel);
            app.FolderListBoxLabel.Text = 'Folder...';
            app.FolderListBox = uilistbox(app.LFHearingDataViewerPanel);
            app.FolderListBox.Multiselect = 'on';
            app.FolderListBox.ValueChangedFcn = createCallbackFcn(app, @FolderListBoxValueChanged, true);
            app.FolderListBox.Value = {'Item 1'};
            app.SelectDataFolderButton = uibutton(app.LFHearingDataViewerPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.EstimatesTab = uitab(app.TabGroup);
            app.EstimatesTab.Title = 'Estimates';
            app.EstimatesTab.Tag = 'Estimates';
            app.GridLayout2 = uigridlayout(app.EstimatesTab);
            app.UIAxesHTL = uiaxes(app.GridLayout2);
            title(app.UIAxesHTL, 'Hearing Threshold -- Left ear')
            xlabel(app.UIAxesHTL, 'Frequency [Hz]')
            ylabel(app.UIAxesHTL, 'Sound Pressure Level [dB re. 20\muPa]')
            zlabel(app.UIAxesHTL, 'Z')
            app.UIAxesHTL.XLim = [63 200];
            app.UIAxesHTL.YLim = [20 100];
            app.UIAxesHTL.XTick = [63 80 100 125 160 200];
            app.UIAxesHTL.XScale = 'log';
            app.UIAxesSTL = uiaxes(app.GridLayout2);
            title(app.UIAxesSTL, 'Spectro-Temporal Modulation Sensitivity -- Left ear')
            xlabel(app.UIAxesSTL, 'Frequency [Hz]')
            ylabel(app.UIAxesSTL, {'Modulation Depth [dB]'; ' '})
            zlabel(app.UIAxesSTL, 'Z')
            app.UIAxesSTL.XLim = [63 200];
            app.UIAxesSTL.YLim = [-50 -10];
            app.UIAxesSTL.XTick = [63 80 100 125 160 200];
            app.UIAxesSTL.XScale = 'log';
            app.UIAxesHTR = uiaxes(app.GridLayout2);
            title(app.UIAxesHTR, 'Hearing Threshold -- Right ear')
            xlabel(app.UIAxesHTR, 'Frequency [Hz]')
            ylabel(app.UIAxesHTR, 'Sound Pressure Level [dB re. 20\muPa]')
            zlabel(app.UIAxesHTR, 'Z')
            app.UIAxesHTR.XLim = [63 200];
            app.UIAxesHTR.YLim = [20 100];
            app.UIAxesHTR.XTick = [63 80 100 125 160 200];
            app.UIAxesHTR.XScale = 'log';
            app.UIAxesSTR = uiaxes(app.GridLayout2);
            title(app.UIAxesSTR, 'Spectro-Temporal Modulation Sensitivity -- Right ear')
            xlabel(app.UIAxesSTR, 'Frequency [Hz]')
            ylabel(app.UIAxesSTR, {'Modulation Depth [dB]'; ' '})
            zlabel(app.UIAxesSTR, 'Z')
            app.UIAxesSTR.XLim = [63 200];
            app.UIAxesSTR.YLim = [-50 -10];
            app.UIAxesSTR.XTick = [63 80 100 125 160 200];
            app.UIAxesSTR.XScale = 'log';
            app.IndividualpresentationsTab = uitab(app.TabGroup);
            app.IndividualpresentationsTab.Title = 'Individual presentations';
            app.IndividualpresentationsTab.Tag = 'IndividualPres';
            app.GridLayout2_2 = uigridlayout(app.IndividualpresentationsTab);
            app.UIAxesHTL_2 = uiaxes(app.GridLayout2_2);
            title(app.UIAxesHTL_2, 'Hearing Threshold -- Left ear')
            xlabel(app.UIAxesHTL_2, 'Frequency [Hz]')
            ylabel(app.UIAxesHTL_2, 'Sound Pressure Level [dB re. 20\muPa]')
            zlabel(app.UIAxesHTL_2, 'Z')
            app.UIAxesHTL_2.XLim = [0 45];
            app.UIAxesHTL_2.YLim = [30 110];
            app.UIAxesHTL_2.XTick = [5 10 15 20 25 30 35 40];
            app.UIAxesHTL_2.XTickLabel = {'5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxesSTL_2 = uiaxes(app.GridLayout2_2);
            title(app.UIAxesSTL_2, 'Spectro-Temporal Modulation Sensitivity -- Left ear')
            xlabel(app.UIAxesSTL_2, 'Frequency [Hz]')
            ylabel(app.UIAxesSTL_2, {'Modulation Depth [dB]'; ' '})
            zlabel(app.UIAxesSTL_2, 'Z')
            app.UIAxesSTL_2.XLim = [0 45];
            app.UIAxesSTL_2.YLim = [-45 5];
            app.UIAxesSTL_2.XTick = [5 10 15 20 25 30 35 40];
            app.UIAxesSTL_2.XTickLabel = {'5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxesHTR_2 = uiaxes(app.GridLayout2_2);
            title(app.UIAxesHTR_2, 'Hearing Threshold -- Right ear')
            xlabel(app.UIAxesHTR_2, 'Frequency [Hz]')
            ylabel(app.UIAxesHTR_2, 'Sound Pressure Level [dB re. 20\muPa]')
            zlabel(app.UIAxesHTR_2, 'Z')
            app.UIAxesHTR_2.XLim = [0 45];
            app.UIAxesHTR_2.YLim = [30 110];
            app.UIAxesHTR_2.XTick = [5 10 15 20 25 30 35 40];
            app.UIAxesHTR_2.XTickLabel = {'5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxesSTR_2 = uiaxes(app.GridLayout2_2);
            title(app.UIAxesSTR_2, 'Spectro-Temporal Modulation Sensitivity -- Right ear')
            xlabel(app.UIAxesSTR_2, 'Frequency [Hz]')
            ylabel(app.UIAxesSTR_2, {'Modulation Depth [dB]'; ' '})
            zlabel(app.UIAxesSTR_2, 'Z')
            app.UIAxesSTR_2.XLim = [0 45];
            app.UIAxesSTR_2.YLim = [-45 5];
            app.UIAxesSTR_2.XTick = [5 10 15 20 25 30 35 40];
            app.UIAxesSTR_2.XTickLabel = {'5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LF_hearing_DataViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end