classdef ResponseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        AnswerPanel  matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        Button_1     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
    end

    
    properties (Access = public)
        Main % Description
        Pushed % Description
        Answer % Description
    end
     methods (Access = public)
        function ButtonReset(app)
            app.Pushed = 0;
            app.Answer = 0;
            app.UIFigure.focus;
        end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Main = mainapp;
            app.Button_1.Enable = 'off';
            app.Button_2.Enable = 'off';
            app.Button_3.Enable = 'off';
            app.Pushed = 0;
            app.Answer = 0;
        end

        % Button pushed function: Button_1, Button_2, Button_3
        function AnswerGiven(app, event)
            % Get which answer was given
            if event.Source == app.Button_1 %% Button A pressed
                app.Answer = 1;
            elseif event.Source == app.Button_2 %% Button B pressed
                app.Answer = 2;
            elseif event.Source == app.Button_3 %% Button C pressed
                app.Answer = 3;
            end
            if ~app.Pushed 
                app.Pushed = 1;
            end
            app.UIFigure.focus;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnswerPanel = uipanel(app.UIFigure);
            app.AnswerPanel.Title = 'Hvilken af de tre lyde er anderledes?';
            app.GridLayout = uigridlayout(app.AnswerPanel);
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @AnswerGiven, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '3.';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @AnswerGiven, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '2.';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @AnswerGiven, true);
            app.Button_1.Enable = 'off';
            app.Button_1.Text = '1.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResponseApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end