classdef ResponseAppThres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        AnswerPanel  matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        Button       matlab.ui.control.Button
    end

    
    properties (Access = public)
        Main % Description
        Pushed % Description
        PushTime % Description
    end
    
    methods (Access = public)
        
        function ButtonReset(app)
            app.Pushed = 0;
            app.PushTime = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Main = mainapp;
            app.Pushed = 0;
            app.Button.Enable = 'off';
        end

        % Button pushed function: Button
        function AnswerGiven(app, event)
            % get time of answer
            app.PushTime = tic;
            if ~app.Pushed % avoid double pushed
               app.Pushed = 1;
            end
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnswerPanel = uipanel(app.UIFigure);
            app.AnswerPanel.Title = 'Tryk på knappen når du hører en lyd.';
            app.GridLayout = uigridlayout(app.AnswerPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @AnswerGiven, true);
            app.Button.Enable = 'off';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResponseAppThres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end