classdef SignalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveButton               matlab.ui.control.Button
        PlaySoundButton          matlab.ui.control.Button
        StimulusparametersPanel  matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        NormTypeSwitch           matlab.ui.control.RockerSwitch
        NormTypeSwitchLabel      matlab.ui.control.Label
        SignalTypeSwitch         matlab.ui.control.RockerSwitch
        SignalTypeSwitchLabel    matlab.ui.control.Label
        LoudCompSwitch           matlab.ui.control.RockerSwitch
        LoudCompSwitchLabel      matlab.ui.control.Label
        ModLeveldBKnob           matlab.ui.control.Knob
        ModLeveldBKnobLabel      matlab.ui.control.Label
        LeveldBFSKnob            matlab.ui.control.Knob
        LeveldBFSKnobLabel       matlab.ui.control.Label
        SignalLengthKnob         matlab.ui.control.Knob
        SignalLengthKnobLabel    matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.DiscreteKnob
        FrequencyKnobLabel       matlab.ui.control.Label
        TimeModHzKnob            matlab.ui.control.Knob
        TimeModHzKnobLabel       matlab.ui.control.Label
        FreqModCOctKnob          matlab.ui.control.Knob
        FreqModCOctKnobLabel     matlab.ui.control.Label
        FreqAxes                 matlab.ui.control.UIAxes
        TimeAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SigFilts % Description
        Signals % Description
        AuOut % Description
        EarphoneSens % Description
        ChanList % Description
    end
    
    methods (Access = private)
        
        function result = playSound(app,signal)
            app.PlaySoundButton.BackgroundColor = [0.2, 0.8, 0.2];
            drawnow
            if playrec('isInitialised')
                pp = playrec('play',repmat(signal,1,2), app.ChanList);
                playrec('block',pp);
            else
                % reshape the signal so that i fits into the buffer size of the
                % sound card 
                bf = app.AuOut.BufferSize;
                sl = length(signal);
                FpB = floor(sl/bf);
                p1 = reshape(signal(1:FpB*bf),[],FpB);
                % if there are samples that dont fill the last frame
                if rem(sl,bf) > 0
                    Zpad = bf - rem(sl,bf);
                    p2 = [signal(FpB*bf+1:end); zeros(Zpad,1)];
                    AudioToDev = [p1 p2];
                else 
                    AudioToDev = p1;
                end
                % play each audio frames, starting and finishing with an empty
                % frame. 
               
                result = 0;
                app.AuOut(zeros(bf,2));
                for i = 1:size(AudioToDev,2)
                    x_ = app.AuOut(repmat(AudioToDev(:,i),1,2));
                    result = result + x_;
                end
                % wait for 1.1 times the length of the signal so compensate for
                % asynchronos sound card playing. The sound card runs in 
                % parallel from the rest of the program. 
                
                app.AuOut(zeros(bf,2));
            end     
            % done playing set the color of the button back to grey
            app.PlaySoundButton.BackgroundColor = [0.95, 0.95, 0.95];
            drawnow
         end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
             %initialize the sound card and lock it for use
            %app.AOut = AudioInit; %choose sampling freq.
%             addpath functions\
%             addpath Calib\
%             
            % initialize sound card
            if playrec('isInitialised')
                app.ChanList = MainApp.ChosenAudioDev.Chanels; 
            else
                app.AuOut =audioDeviceWriter;
                b = app.AuOut.getAudioDevices;
                fs = 48e3; 
                app.ChanList = [1 2]; 
                app.AuOut.Device = b{1};
                app.AuOut.SampleRate = fs;
                app.AuOut.ChannelMappingSource = 'Property';
                app.AuOut.ChannelMapping = app.ChanList;
                app.AuOut(zeros(app.AuOut.BufferSize,length(app.ChanList)));
            end 
            % load loudness filters and lpfilter for the signal
            app.SigFilts = load('LoudEQFilters.mat',"Levs","Lfilt","Hlp");
            % load deafult earphone sensitivity
            app.EarphoneSens = load('IP30_ID205470_0_40dBAtt_14_10_2022.mat','LdB','Frequencies');
            % read default frequencies for threshold
            % load 1/3-octave center frequencies
            filtbank = octaveFilterBank('1/3 octave',48000);
            FreqsC  = getCenterFrequencies(filtbank);
            clear filtbank;
            app.FreqAxes.XTick = round([12.6 15.8 20 FreqsC]);
            app.FreqAxes.XMinorTick = 'off';
            app.FreqAxes.XMinorGrid = 'off';
            
        end

        % Value changed function: FreqModCOctKnob, FrequencyKnob, 
        % ...and 7 other components
        function UpdateSignal(app, event)
            % get Values for new stimuli 
            CO = round(app.FreqModCOctKnob.Value);
            TM = round(app.TimeModHzKnob.Value);
            SigLev = round(app.LeveldBFSKnob.Value);
            ModLev = round(app.ModLeveldBKnob.Value);
            Freq = str2double(app.FrequencyKnob.Value);
            T = roundn(app.SignalLengthKnob.Value,-1);
            LoudEq = app.LoudCompSwitch.Value;
            NormType = app.NormTypeSwitch.Value;
            SigType = app.SignalTypeSwitch.Value;
            % deactivate Loudness compensation for frequencies above 125 Hz
            if Freq > 250
                app.LoudCompSwitch.Value = "Off";
                app.LoudCompSwitch.Enable = "off";
            else
                app.LoudCompSwitch.Enable = "on";
            end
            % update labels
            COLabel = regexp(app.FreqModCOctKnobLabel.Text,':','split');
            app.FreqModCOctKnobLabel.Text = strcat(COLabel{1},':', string(CO));
            TMLabel = regexp(app.TimeModHzKnobLabel.Text,':','split');
            app.TimeModHzKnobLabel.Text = strcat(TMLabel{1},':', string(TM));            
            SigLevLabel = regexp(app.LeveldBFSKnobLabel.Text, ':','split');
            app.LeveldBFSKnobLabel.Text = strcat(SigLevLabel{1},':',string(SigLev));            
            ModLevLabel = regexp(app.ModLeveldBKnobLabel.Text, ':','split');
            app.ModLeveldBKnobLabel.Text = strcat(ModLevLabel{1},':',string(ModLev));            
            FreqLabel = regexp(app.FrequencyKnobLabel.Text, ':','split');
            app.FrequencyKnobLabel.Text = strcat(FreqLabel{1},':',app.FrequencyKnob.Value);            
            SigLengthLabel = regexp(app.SignalLengthKnobLabel.Text, ':','split');
            app.SignalLengthKnobLabel.Text = strcat(SigLengthLabel{1},':',string(T));
            % Generate stimuli
            if playrec('isInitialised')
                fs = playrec('getSampleRate');
            else
                fs = app.AuOut.SampleRate;
            end
            if strcmp(SigType,'Tone')
                [smod, n1, ~,~] = generate_STM_3(Freq,CO,TM,ModLev,T,fs,SigLev,app.SigFilts,app.EarphoneSens,LoudEq,NormType);
            else
                [smod, n1, ~, ~, ~] = generate_STM_new(0.5,Freq,CO,TM,ModLev,T,fs,SigLev,app.SigFilts,app.EarphoneSens,LoudEq,NormType);
            end
            N = length(smod);
            t = 0:1/fs:T-1/fs;
            F = 0:fs/N:fs/2-fs/N;
            % fft of signal
            Smod = fft(smod,N);
            N1 = fft(n1,N);
    
            % 
            SMOD = 20*log10(sqrt(2*abs(Smod(1:N/2,:)/N).^2));
            NO1 = 20*log10(sqrt(2*abs(N1(1:N/2,:)/N).^2));
            plot(app.TimeAxes,t,n1,t,smod);
            plot(app.FreqAxes,F,NO1,F,SMOD);
            app.Signals.smod = smod;
            app.Signals.n1 = n1;
            Leg{1} = sprintf('Carrier: %3.1f [dBFS]', 20*log10(rms(n1)));
            Leg{2} = sprintf('Modulated: %3.1f [dBFS]', 20*log10(rms(smod)));
            legend(app.FreqAxes,Leg,'Location',"northwest")
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~playrec('isInitialised') && exist('app.AuOut','var') 
                release(app.AuOut)
            end
            delete(app)
            
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
             playSound(app, app.Signals.smod);

             playSound(app, app.Signals.n1);
             
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try 
                [Name, Path] = uiputfile('*.mat','Type the name of the file to save.');
                ModSig = app.Signals.smod;
                Signal = app.Signals.n1;
                save([Path,'\',Name],"ModSig","Signal");
                uialert(app.UIFigure,'File saved!','Signal Generator','Icon','success')
            catch
                uialert(app.UIFigure,'No file saved!','Signal Generator','Icon','error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TimeAxes = uiaxes(app.UIFigure);
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.YLim = [-1.1 1.1];
            app.FreqAxes = uiaxes(app.UIFigure);
            title(app.FreqAxes, ' ')
            xlabel(app.FreqAxes, 'Frequency [Hz]')
            ylabel(app.FreqAxes, 'dBFS')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XLim = [10 1000];
            app.FreqAxes.YLim = [-80 0];
            app.FreqAxes.XTick = [20 30 40 50 63 80 100 125 160 200 250 300 400 500 630 800 1000];
            app.FreqAxes.XScale = 'log';
            app.StimulusparametersPanel = uipanel(app.UIFigure);
            app.StimulusparametersPanel.Title = 'Stimulus parameters';
            app.GridLayout = uigridlayout(app.StimulusparametersPanel);
            app.FreqModCOctKnobLabel = uilabel(app.GridLayout);
            app.FreqModCOctKnobLabel.Text = 'Freq Mod [C/Oct]';
            app.FreqModCOctKnob = uiknob(app.GridLayout, 'continuous');
            app.FreqModCOctKnob.Limits = [0 50];
            app.FreqModCOctKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.FreqModCOctKnob.MinorTicks = [];
            app.TimeModHzKnobLabel = uilabel(app.GridLayout);
            app.TimeModHzKnobLabel.Text = 'Time Mod [Hz]';
            app.TimeModHzKnob = uiknob(app.GridLayout, 'continuous');
            app.TimeModHzKnob.Limits = [0 50];
            app.TimeModHzKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.TimeModHzKnob.MinorTicks = [];
            app.FrequencyKnobLabel = uilabel(app.GridLayout);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.GridLayout, 'discrete');
            app.FrequencyKnob.Items = {'63', '80', '100', '125', '160', '200', '250', '315', '400', '500'};
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.FrequencyKnob.Value = '80';
            app.SignalLengthKnobLabel = uilabel(app.GridLayout);
            app.SignalLengthKnobLabel.Text = 'Signal Length';
            app.SignalLengthKnob = uiknob(app.GridLayout, 'continuous');
            app.SignalLengthKnob.Limits = [1 5];
            app.SignalLengthKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.SignalLengthKnob.MinorTicks = [];
            app.SignalLengthKnob.Value = 1;
            app.LeveldBFSKnobLabel = uilabel(app.GridLayout);
            app.LeveldBFSKnobLabel.Text = 'Level [dBFS]';
            app.LeveldBFSKnob = uiknob(app.GridLayout, 'continuous');
            app.LeveldBFSKnob.Limits = [-60 0];
            app.LeveldBFSKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.LeveldBFSKnob.Value = -6;
            app.ModLeveldBKnobLabel = uilabel(app.GridLayout);
            app.ModLeveldBKnobLabel.Text = 'Mod Level [dB]';
            app.ModLeveldBKnob = uiknob(app.GridLayout, 'continuous');
            app.ModLeveldBKnob.Limits = [-80 0];
            app.ModLeveldBKnob.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.LoudCompSwitchLabel = uilabel(app.GridLayout);
            app.LoudCompSwitchLabel.Text = 'Loud Comp';
            app.LoudCompSwitch = uiswitch(app.GridLayout, 'rocker');
            app.LoudCompSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.SignalTypeSwitchLabel = uilabel(app.GridLayout);
            app.SignalTypeSwitchLabel.Text = 'Signal Type';
            app.SignalTypeSwitch = uiswitch(app.GridLayout, 'rocker');
            app.SignalTypeSwitch.Items = {'Tone', 'Noise'};
            app.SignalTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.SignalTypeSwitch.Value = 'Tone';
            app.NormTypeSwitchLabel = uilabel(app.GridLayout);
            app.NormTypeSwitchLabel.Text = 'Norm Type';
            app.NormTypeSwitch = uiswitch(app.GridLayout, 'rocker');
            app.NormTypeSwitch.Items = {'Peak', 'RMS'};
            app.NormTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSignal, true);
            app.NormTypeSwitch.Value = 'Peak';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'PlaySound';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalGenerator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end