classdef SoundCardSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DoneButton                      matlab.ui.control.Button
        RightoutputchannelEditField     matlab.ui.control.NumericEditField
        RightoutputchannelEditFieldLabel  matlab.ui.control.Label
        LeftoutputchanelEditField       matlab.ui.control.NumericEditField
        LeftoutputchanelEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SelectSoundCardListBox          matlab.ui.control.ListBox
        SelectSoundCardListBoxLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Devices % Description
        chosenDev % Description
        mainapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            H = playrec('getDevices');
            Devs = struct2table(H);
            Devs = Devs(Devs.outputChans > 0,:);
            Devs = Devs(contains(Devs.hostAPI, 'ASIO') | contains(Devs.hostAPI, 'Windows WASAPI'),:);
            app.Devices = Devs;
            app.SelectSoundCardListBox.Items = app.Devices.name;
             
        end

        % Value changed function: SelectSoundCardListBox
        function SelectSoundCardListBoxValueChanged(app, event)
           SC = app.SelectSoundCardListBox.Value;
           app.chosenDev = app.Devices(strcmp(app.Devices.name, SC),:);
           app.SamplingFrequencyHzEditField.Value = app.chosenDev.defaultSampleRate;
           app.LeftoutputchanelEditField.Value = app.chosenDev.outputChans - 1;
           app.RightoutputchannelEditField.Value = app.chosenDev.outputChans;
           app.chosenDev.Chanels = [app.chosenDev.outputChans - 1 app.chosenDev.outputChans];
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            fs = app.SamplingFrequencyHzEditField.Value;
            app.chosenDev.defaultSampleRate = fs;
        end

        % Value changed function: LeftoutputchanelEditField, 
        % ...and 1 other component
        function LeftoutputchanelEditFieldValueChanged(app, event)
            lchan = app.LeftoutputchanelEditField.Value;
            rchan = app.RightoutputchannelEditField.Value;
            app.chosenDev.Chanels = [lchan rchan];
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.mainapp.ChosenAudioDev = app.chosenDev;
            delete(app)
        end

        % Clicked callback: SelectSoundCardListBox
        function SelectSoundCardListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            SC = app.SelectSoundCardListBox.Value;
            app.chosenDev = app.Devices(strcmp(app.Devices.name, SC),:);
            app.SamplingFrequencyHzEditField.Value = app.chosenDev.defaultSampleRate;
            app.LeftoutputchanelEditField.Value = app.chosenDev.outputChans - 1;
            app.RightoutputchannelEditField.Value = app.chosenDev.outputChans;
            app.chosenDev.Chanels = [app.chosenDev.outputChans - 1 app.chosenDev.outputChans];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectSoundCardListBoxLabel = uilabel(app.UIFigure);
            app.SelectSoundCardListBoxLabel.Text = 'Select Sound Card:';
            app.SelectSoundCardListBox = uilistbox(app.UIFigure);
            app.SelectSoundCardListBox.ValueChangedFcn = createCallbackFcn(app, @SelectSoundCardListBoxValueChanged, true);
            app.SelectSoundCardListBox.ClickedFcn = createCallbackFcn(app, @SelectSoundCardListBoxClicked, true);
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency [Hz]';
            app.SamplingFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.LeftoutputchanelEditFieldLabel = uilabel(app.UIFigure);
            app.LeftoutputchanelEditFieldLabel.Text = 'Left output chanel:';
            app.LeftoutputchanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftoutputchanelEditField.ValueChangedFcn = createCallbackFcn(app, @LeftoutputchanelEditFieldValueChanged, true);
            app.RightoutputchannelEditFieldLabel = uilabel(app.UIFigure);
            app.RightoutputchannelEditFieldLabel.Text = 'Right output channel:';
            app.RightoutputchannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightoutputchannelEditField.ValueChangedFcn = createCallbackFcn(app, @LeftoutputchanelEditFieldValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundCardSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end