classdef ThresholdAppMultiWin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalibrationFileMenu        matlab.ui.container.Menu
        PatientMenu                matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        SoundCardMenu              matlab.ui.container.Menu
        ASIOFirefaceUSBmenu        matlab.ui.container.Menu
        ChooseSCMenu               matlab.ui.container.Menu
        ResetSCMenu                matlab.ui.container.Menu
        ToolsMenu                  matlab.ui.container.Menu
        SignalGeneratorMenu        matlab.ui.container.Menu
        DataViewerMenu             matlab.ui.container.Menu
        TestandEarPanel            matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        TestTypeSwitch             matlab.ui.control.Switch
        TestTypeSwitchLabel        matlab.ui.control.Label
        EarSwitchLabel             matlab.ui.control.Label
        EarSwitch                  matlab.ui.control.Switch
        STMparametersPanel         matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        CenterFrequencyKnobLabel   matlab.ui.control.Label
        SensationLeveldBKnobLabel  matlab.ui.control.Label
        CenterFrequencyKnob        matlab.ui.control.DiscreteKnob
        SensationLeveldBKnob       matlab.ui.control.Knob
        TimeModKnobLabel           matlab.ui.control.Label
        FreqModKnobLabel           matlab.ui.control.Label
        TimeModKnob                matlab.ui.control.DiscreteKnob
        FreqModKnob                matlab.ui.control.DiscreteKnob
        FreetestmodeSwitch         matlab.ui.control.Switch
        FreetestmodeLabel          matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        ThresParamPanel            matlab.ui.container.Panel
        f200                       matlab.ui.control.CheckBox
        f160                       matlab.ui.control.CheckBox
        f125                       matlab.ui.control.CheckBox
        f100                       matlab.ui.control.CheckBox
        f80                        matlab.ui.control.CheckBox
        f63                        matlab.ui.control.CheckBox
        CurrentStimuliLabel        matlab.ui.control.Label
        PatinetInfoPanel           matlab.ui.container.Panel
        GenderLabel                matlab.ui.control.Label
        AgeLabel                   matlab.ui.control.Label
        TypeLabel                  matlab.ui.control.Label
        PatientLabel               matlab.ui.control.Label
        STMDataTab                 matlab.ui.control.Table
        ThresDataTab               matlab.ui.control.Table
        SpectrotemporalsensitivityLabel  matlab.ui.control.Label
        HearingthresholdLabel      matlab.ui.control.Label
        LFhearingLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    %% Variables used in the app. 
    properties (Access = public)
        CurrentSignal % Parameters of the last signal generated
        TrialNr % Number of trials presented of 3AFC proceedure
        Response % Data structure for 3AFC proceedure
        STMTrialParam % Paramenters for the last STM Thres trial
        Reversals % Number of revesals of 3AFC proceedure
        RespApp % Subject response window
        TestType % Threshold or STM detection threshold
        StepSize % Steps in the parameter change of 3AFC proceedure
        SigFilts % Filters for conditioning the audio signal
        PatientID % Name of data record
        ThresData % Data from threshold determinations
        LatestThres % data from previous trials
        EarphoneSens % matrix with values fro headphone calibration
        DataFolder % string with name for data folder.
        PatientFile % structure with file name and path for response file
        ChanNumbers % Sound cards output channel number for left and right  
        FileIO % File handler for output file
        ReadyToStart % Boolean flag to turn on start button 
        ChosenAudioDev % Description
        ard % Arduino handle.
        ardconf % Pin configuration of arduino board. 
    end
    
    properties (Access = private)
         % Description
        % Description
        
    end
    
    
    %% Methods available in the app
    methods (Access = public)
     
        %% Threshold funciton. 
        function Thres(app)
            % get chosen frequencies
            F = str2double(app.CenterFrequencyKnob.Items); 
            tF = F(app.ThresData.tFSelect);
                  
            %start threshold routine
            MenuReset(app,'off');
            [app.ThresData.Raw, app.ThresData.Estimate] = AbsThres(app,tF);
            app.ThresData.Reps = app.ThresData.Reps+1; 
            SaveResults(app)
            app.StartButton.Enable = 'on'; %save before continuing
            app.StartButton.Text = 'Start';
                     
        end
        %% Run STMT rutine
        function STMT_3AFC(app)
            if strcmp(app.FreetestmodeSwitch.Value, 'Off')
                for Modulations = 1:2
                    CyclesOctave = round(str2double(app.FreqModKnob.Value));
                    if CyclesOctave < 8 %&& Modulations == 2
                        app.FreqModKnob.Value = '24';
                        app.TimeModKnob.Value = '24';
                    else 
                        app.FreqModKnob.Value = '2';
                        app.TimeModKnob.Value = '2';
                    end
                    MenuReset(app,'off');
                    STMThres(app,Modulations); 
                    app.ThresData.Reps = app.ThresData.Reps+1; 
                    SaveResults(app)
                    MenuReset(app,'off');
                    app.StartButton.Text = 'Stop';
                    app.StartButton.Enable = 'on';
                    GuiReset(app,'off')
                end
            else
               MenuReset(app,'off'); 
               STMThres(app,3);
               app.ThresData.Reps = app.ThresData.Reps+1; 
               SaveResults(app)
                    
            end
            app.StartButton.Enable = 'on'; 
            app.StartButton.Text = 'Start';
            MenuReset(app,'on');
            GuiReset(app,'on');
            app.ThresParamPanel.Enable = 'off';

        end
        %% Reset GUI
        function GuiReset(app,State)
            switch State
                case 'off'
                    app.StartButton.Enable = "off";
                    app.ThresParamPanel.Enable = "off";
                    app.STMparametersPanel.Enable ="off";
                    app.TestandEarPanel.Enable = "off";
                    
                case 'on'
                    app.StartButton.Enable = "on";
                    app.ThresParamPanel.Enable = "on";
                    app.STMparametersPanel.Enable ="on";
                    app.TestandEarPanel.Enable = "on";
            end
        
        end
        %% Reset Menu
        function MenuReset(app,State)
            switch State
                case 'off'
                    app.PatientMenu.Enable = "off";
                    app.SoundCardMenu.Enable = "off";
                    app.CalibrationFileMenu.Enable = "off";
                    app.ToolsMenu.Enable = "off";
                case 'on'
                    app.PatientMenu.Enable = "on";
                    app.SoundCardMenu.Enable = "on";
                    app.CalibrationFileMenu.Enable = "on";
                    app.ToolsMenu.Enable = "on";
            end
        
        end

    end
    methods (Access = private)
        %% Chech that SC and response file are ready
        function Ready = Check4Start(app)
            if playrec('isInitialised') && ~isempty(app.FileIO)
                Ready = true;
                app.StartButton.Enable = 'on';
            else
                Ready = false;
                app.StartButton.Enable = 'off';
            end
        end
        %% Save results 
        function SaveResults(app)

            d = datestr(now,'dd_mmm_yyyy_HH_MM_SS');
            
            if strcmp(app.TestTypeSwitch.Value,'STM')
                Name = sprintf('STMT_%s',d);
                if ~isempty(app.Response)
                    temp.Resp = struct2table(app.Response);
                    temp.StimParam = app.STMTrialParam;
                    temp.Ear = app.EarSwitch.Value;
                    Data.(Name) = temp;
                end    
            else
                if isfield(app.ThresData,'Estimate')
                    Name = sprintf('Thres_%s',d);      
                    Data.(Name) = app.ThresData;
                end
            end
            
            if ~exist("Data","var")
                uialert(app.UIFigure, 'No data to save!','',Icon='info')
            elseif contains(Name,'STMT') && height(Data.(Name).Resp) < 10
                uialert(app.UIFigure,'Not enough data to calculate a threshold, no data saved!','','Icon','info')
            elseif contains(Name,'Thres') && size(Data.(Name).Raw,1) < 10
                uialert(app.UIFigure,'Not enough data to calculate a threshold, no data saved!','','Icon','info')
            else
                app.FileIO.(Name) = Data.(Name);
                uialert(app.UIFigure,'Data saved!','','Icon','success')
                
                %save([app.PatientFile.Path, app.PatientFile.Name],"-struct","Data",Name,'-append'); 
                % Update data on tables
                dFF = ThresEstimates(Data);
                if ~isempty(dFF.stmT)
                    stmdata = [app.STMDataTab.Data; dFF.stmT];
                    app.STMDataTab.Data = stmdata;
                    app.STMDataTab.RowName = stmdata.Properties.RowNames;
                end
                if ~isempty(dFF.ths)
                    tdata = [app.ThresDataTab.Data; dFF.ths];
                    app.ThresDataTab.Data = tdata;
                    app.ThresDataTab.RowName = tdata.Properties.RowNames;
                end
                    
            end
                
            % prepare to continue
            
            app.TestTypeSwitch.Enable = 'on';
            if strcmp(app.TestType,'Thres')
                app.STMparametersPanel.Enable = 'off';
                app.ThresParamPanel.Enable = 'on';
            else
                app.STMparametersPanel.Enable = 'on';
                app.ThresParamPanel.Enable = 'off';
            end
            app.ThresDataTab.Enable = 'on';
            app.TestandEarPanel.Enable = 'on';
            app.StartButton.Text = 'Start';
            app.StartButton.Enable = 'on';
            MenuReset(app,'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%            addpath functions\
%            addpath Calib\
            GuiReset(app,'off')
            
            app.STMDataTab.Enable = 'on';
            app.ThresDataTab.Enable = 'on';
            % load loudness filters and lpfilter for the signal
            app.SigFilts = load('LoudEQFilters.mat',"Levs","Lfilt","Hlp");
            % load earphone sensitivity
            app.EarphoneSens = load('IP30_ID205470_0_40dBAtt_14_10_2022.mat','LdB','Frequencies');
            % read default frequencies for threshold
            tFSel(6) = app.f200.Value;
            tFSel(5) = app.f160.Value;
            tFSel(4) = app.f125.Value;
            tFSel(3) = app.f100.Value;
            tFSel(2) = app.f80.Value;
            tFSel(1) = app.f63.Value;
            app.ThresData.tFSelect = tFSel;
            % initialize the ASIO Fireface USB
            [app.ChanNumbers, ~] = PlayrecInit();
            if playrec('isInitialised')
                app.ASIOFirefaceUSBmenu.Checked = true;
            else
                app.ASIOFirefaceUSBmenu.Checked = false;
            end
            % initialize response button for threshold. 

            try
                app.ard = arduino;
            catch
                app.ard = nan;
            end
            if isobject(app.ard)
                %% Pin Layout for arduino uno board for response button.
                app.ardconf.LED = 'D7'; % high led is on
                app.ardconf.RES = 'D8'; % Reset SWOutPressed when low else is high. 
                app.ardconf.SWState = 'D5'; % 1 if up 0 if down
                app.ardconf.SWPressed = 'D6'; % 1 after pressed until RES is low.

                configurePin(app.ard,app.ardconf.SWState,'pullup')
                configurePin(app.ard,app.ardconf.SWPressed,'pullup')
                configurePin(app.ard,app.ardconf.RES,'DigitalOutput')
                app.ard.writeDigitalPin(app.ardconf.RES,0)
                configurePin(app.ard,app.ardconf.LED,'DigitalOutput')
                app.ard.writeDigitalPin(app.ardconf.LED,0)

                uialert(app.UIFigure,'Pushbutton connected. Threshold test can use both puchbotton or screen button.','Pushbutton connected','Icon','success')
            else
                uialert(app.UIFigure,'There is not pushbutton connected. Threshold test will default to click on screen.','There is not pushbutton connected','Icon','error')
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if playrec('isInitialised')
                if strcmp(app.StartButton.Text,'Start') 
                % Disabel stim parameter control
                    GuiReset(app,'off')
                    app.RespApp.AnswerPanel.Enable = 'on';
                    app.StartButton.Enable = 'on';
                    app.StartButton.Text  = 'Stop';
                    if strcmp(app.TestType,'Thres')
                        Thres(app);
                    else
                        STMT_3AFC(app);
                    end
                    
                else
                    app.Reversals = 5;
                    app.StartButton.Text  = 'Start';
                    app.StartButton.Enable  = 'off';
                    app.RespApp.AnswerPanel.Enable = 'off';
                    app.RespApp.AnswerPanel.Title = 'Til til en pause';
                    if isempty(app.STMDataTab.Data)
                        app.TestTypeSwitch.Value = 'Thres';
                        app.TestTypeSwitch.Enable = 'off';
                    else
                        app.TestTypeSwitch.Enable = 'on';
                    end

                end
            else
                uialert(app.UIFigure,...
                    'The sound card is not configured. Use the Sound Card menu to choose one.',...
                    '','icon','error')
            end

        end

        % Value changed function: TestTypeSwitch
        function TestTypeSwitchValueChanged(app, event)
            app.TestType = app.TestTypeSwitch.Value;
            app.StartButton.Enable = 'on';
            switch app.TestType 
                case 'STM'
                    cla(app.UIAxes)
                    app.UIAxes.YLim = [-75 5];
                    app.UIAxes.YLabel.String = 'Modulation Amplitude [dB]';
                    app.StepSize.Up = 10;
                    app.StepSize.Dn = 3;
                    app.StepSize.Max = 0;
                    app.StepSize.Min = -100;
                    if isempty(findprop(app.RespApp, 'AButton')) 
                        delete(app.RespApp);
                        app.RespApp = ResponseApp(app);
                    end
                    app.STMparametersPanel.Enable = 'on';
                    app.FreetestmodeSwitchValueChanged(app.FreetestmodeSwitch);
                    app.ThresParamPanel.Enable = 'off';
                case 'Thres'
                    cla(app.UIAxes)                    
                    app.UIAxes.YLim = [0 120];
                    app.UIAxes.YLabel.String = 'dB SPL';
                    app.StepSize.Up = 5;
                    app.StepSize.Dn = -10;
                    app.StepSize.Max = -4;
                    app.StepSize.Min = -100;
                    if isempty(findprop(app.RespApp, 'Button')) 
                        delete(app.RespApp);
                        app.RespApp = ResponseAppThres(app);
                    end
                    app.STMparametersPanel.Enable = 'off';
                    app.ThresParamPanel.Enable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if playrec('isInitialised')
                playrec('reset')
            end
            delete(app.RespApp)
            delete(app)
            
        end

        % Menu selected function: LoadMenu, NewMenu
        function GetCreatePatientID(app, event)
            d = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message","Reading data...");
            delete(app.RespApp)

            if strcmp(event.Source.Text,'Load')
                [app.PatientFile.Name, app.PatientFile.Path] = uigetfile('data\*.mat','Select existing data file.');
                
            else
                [app.PatientFile.Name, app.PatientFile.Path] = uiputfile('data\*.mat','Type the name of a new data file.');
                
            end
            
            if app.PatientFile.Name ~= 0
                app.FileIO = matfile([app.PatientFile.Path,app.PatientFile.Name],"Writable",true);
                OldFile = exist([app.PatientFile.Path,app.PatientFile.Name],'file');
                if OldFile
                    app.TestandEarPanel.Enable = 'on';
                    % get data from the file
                    dFF = ThresEstimates(app.PatientFile);
                    if ~isempty(dFF.stmT)
                        app.STMDataTab.Data = dFF.stmT;
                        app.STMDataTab.RowName = dFF.stmT.Row;
                    else
                        app.STMDataTab.Data = [];
                    end
                    if ~isempty(dFF.ths)
                        app.ThresDataTab.Data = dFF.ths;
                        app.ThresDataTab.RowName = dFF.ths.Row;
                        app.TestTypeSwitch.Enable = "on";
                    else
                        app.ThresDataTab.Data = [];
                        app.TestTypeSwitch.Value = "Thres";
                        app.TestTypeSwitch.Enable = "off";
                        cla(app.UIAxes)                    
                        app.UIAxes.YLim = [0 120];
                        app.UIAxes.YLabel.String = 'dB SPL';
                        app.StepSize.Up = 5;
                        app.StepSize.Dn = -10;
                        app.StepSize.Max = -3;
                        app.StepSize.Min = -100;
                    end
                    
                    % if there is no Patient field in the file, get the
                    % information from the file name: TTXX_YYyG,
                    % TT patient type NH (normal hearing) CI (cochlear implant)
                    % XX ID number from 01 t0 99
                    % YY age in years
                    % G gender M (male), F (female)
                    if isempty(who(app.FileIO,"Patient"))
                        ID = regexp(app.PatientFile.Name,'_','split');
                        AgeSex = regexp(ID{2},'y','split');
                        Patient.ID = ID{1}; % NH01 -- NH99 ; CI01 --CI99
                        Patient.Age = str2double(AgeSex{1});
                        Patient.Gender = AgeSex{2}(1);
                        Patient.Type = ID{1}(1:2);
                        app.PatientID = Patient;
                        app.FileIO.Patient = Patient;
                    else
                        app.PatientID = app.FileIO.Patient;
                    end
    
                else
                    app.TestTypeSwitch.Value = "Thres";
                    app.TestTypeSwitch.Enable = 'off';
                    app.STMDataTab.Data = [];
                    app.ThresDataTab.Data = [];
                    ID = regexp(app.PatientFile.Name,'_','split');
                    AgeSex = regexp(ID{2},'y','split');
                    Patient.ID = ID{1}; % NH01 -- NH99 ; CI01 --CI99
                    Patient.Age = str2double(AgeSex{1});
                    Patient.Gender = AgeSex{2}(1);
                    Patient.Type = ID{1}(1:2);
                    app.PatientID = Patient;
                    app.FileIO.Patient = Patient;
                    
                end
                %get ready to start.
                app.TypeLabel.Text =  ['Type: ',app.PatientID.Type];
                app.GenderLabel.Text = ['Gender: ', app.PatientID.Gender];
                app.PatientLabel.Text = ['Patient: ', app.PatientID.ID];
                app.AgeLabel.Text = ['Age: ', num2str(app.PatientID.Age)];
                app.TestandEarPanel.Enable = 'on';  
                app.TestTypeSwitch.Value = 'Thres';
                app.TestType = app.TestTypeSwitch.Value;
                switch app.TestType 
                    case 'STM'
                        app.ThresParamPanel.Enable = 'off';
                        app.STMparametersPanel.Enable = 'on';
                        app.FreetestmodeSwitchValueChanged(app.FreetestmodeSwitch);
                        app.RespApp = ResponseApp(app);
                    case 'Thres'
                        app.STMparametersPanel.Enable = 'off';
                        app.ThresParamPanel.Enable = 'on';
                        app.RespApp = ResponseAppThres(app);
                end
                app.TestTypeSwitchValueChanged(app.TestTypeSwitch);
                
                app.ThresData.Reps = 0;
                close(d)
            else
                uialert(app.UIFigure,'No Patient file was loaded or created, try again.','','Icon','error')
                app.FileIO = 0;
            end
            Check4Start(app);

        end

        % Display data changed function: STMDataTab, ThresDataTab
        function UpdateTabsFromFileData(app, event)
            newDisplayData = event.Source.DisplayData;
            event.Source.Data = newDisplayData;
        end

        % Value changed function: f100, f125, f160, f200, f63, f80
        function ThresFrequencies(app, event)
            tFSel(6) = app.f200.Value;
            tFSel(5) = app.f160.Value;
            tFSel(4) = app.f125.Value;
            tFSel(3) = app.f100.Value;
            tFSel(2) = app.f80.Value;
            tFSel(1) = app.f63.Value;
            app.ThresData.tFSelect = tFSel;
            
            
        end

        % Value changed function: FreqModKnob, SensationLeveldBKnob, 
        % ...and 1 other component
        function FreqModKnobValueChanged(app, event)
%             value = round(app.SensationLeveldBKnob.Value);
%             app.SensationLeveldBKnob.Value = value;
% %             value = round(str2double(app.TimeModKnob.Value));
% %             app.TimeModKnob.Value = value;
% %             value = round(str2double(app.FreqModKnob.Value));
% %             app.FreqModKnob.Value = value;
            
        end

        % Menu selected function: CalibrationFileMenu
        function PushToolClicked(app, event)
            [CalibrationFile, CalibrationFilePath] = uigetfile('Calib\IP30_ID*.mat');
            if CalibrationFile ~= 0
                fi = matfile([CalibrationFilePath, CalibrationFile]);
                if sum(ismember(who(fi),{'LdB','Frequencies'})) == 2
                    app.EarphoneSens = load(fi.Properties.Source,"LdB","Frequencies"); 
                    uialert(app.UIFigure,sprintf('Now using calibration file: %s ', CalibrationFile),'Calibration Set-up','Icon','success')
                else
                    uialert(app.UIFigure,...
                        sprintf('No calibration data found, using calibration data from: %s ',...
                        'IP30_ID205470_0_40dBAtt_14_10_2022.mat'),'Calibration Set-up','Icon','warning')
                end
            else
                 uialert(app.UIFigure,...
                        sprintf('Could not find calibration file, using calibration data from: %s ',...
                        'IP30_ID205470_0_40dBAtt_14_10_2022.mat'),'Calibration Set-up','Icon','error')
            end
        end

        % Menu selected function: ASIOFirefaceUSBmenu
        function SoundCardMenuSelected(app, event)

        % initialize the sound card and lock it for use
        % choose sampling freq, sound card and
        % max number of channels. 
        app.ASIOFirefaceUSBmenu.Checked = false;
        app.ChooseSCMenu.Checked = false;
        fs = 48e3; 
        SC = 'ASIO Fireface USB';
        NumChan = 8;
        
        
        [app.ChanNumbers, SC_] = PlayrecInit(fs,SC,NumChan);
        if strcmp(SC,SC_)    
            MsG = sprintf('\n Sound Card: %s is initialized.\n Using channel outputs %d and %d. ',... 
                SC,app.ChanNumbers(1), app.ChanNumbers(2));
            uialert(app.UIFigure,MsG,'Sound Set-up','Icon','success')
            app.ASIOFirefaceUSBmenu.Checked = true;
        elseif SC_ == 0
                MsG = sprintf('\n Sound Card: %s could not be found, check connection.',SC);
                uialert(app.UIFigure,MsG,'Sound Set-up','Icon','Error')
        else
            MsG = sprintf('\n Sound Card: %s is already initialized, use "Reset SC" menu item to change sound card.\n' ,SC_);
            uialert(app.UIFigure,MsG,'Sound Set-up','Icon','warning')
        end
        Check4Start(app);

        end

        % Menu selected function: ResetSCMenu
        function ResetSCMenuSelected(app, event)
            playrec('reset')
            MsG = sprintf('\n No sound card is currently active!\n');
            uialert(app.UIFigure,MsG,'Sound Set-up','Icon','warning')
            GuiReset(app,'off')
            app.StartButton.Enable = "off";
            app.ASIOFirefaceUSBmenu.Checked = false;
            app.ChooseSCMenu.Checked = false;
            app.ChooseSCMenu.Text = 'Choose SC';

        end

        % Menu selected function: ChooseSCMenu
        function ChooseSCMenuSelected(app, event)
            ssf = SoundCardSelection(app);
            waitfor(ssf)
            if ~isempty(app.ChosenAudioDev)
                [app.ChanNumbers, SC_] = PlayrecInit(app.ChosenAudioDev.defaultSampleRate,...
                    app.ChosenAudioDev.name{1},app.ChosenAudioDev.Chanels(2));
            end
            if strcmp(app.ChosenAudioDev.name,SC_)    
                MsG = sprintf('\n Sound Card: %s is initialized.\n Using channel outputs %d and %d. ',... 
                    SC_,app.ChanNumbers(1), app.ChanNumbers(2));
                uialert(app.UIFigure,MsG,'Sound Set-up','Icon','success')
                app.ChooseSCMenu.Text = SC_;
                app.ChooseSCMenu.Checked = true;
            elseif SC_ == 0
                MsG = sprintf('\n Sound Card: %s could not be found, check connection.',app.ChosenAudioDev.name);
                uialert(app.UIFigure,MsG,'Sound Set-up','Icon','Error')
            else
                MsG = sprintf('\n Sound Card: %s is already initialized, use "Reset SC" menu item to change sound card.\n' ,SC_);
                uialert(app.UIFigure,MsG,'Sound Set-up','Icon','warning')
            end
            Check4Start(app);
        end

        % Menu selected function: SignalGeneratorMenu
        function SignalGeneratorMenuSelected(app, event)
            SignalGenerator(app)
        end

        % Menu selected function: DataViewerMenu
        function DataViewerMenuSelected(app, event)
            LF_hearing_DataViewer(app)
        end

        % Value changed function: FreetestmodeSwitch
        function FreetestmodeSwitchValueChanged(app, event)
            value = app.FreetestmodeSwitch.Value;
            switch value
                case 'Off'
                    app.FreqModKnob.Value = '2';
                    app.TimeModKnob.Value = '2';
                    app.SensationLeveldBKnob.Value = 40;
                    app.FreqModKnob.Enable = 'off';
                    app.TimeModKnob.Enable = 'off';
                    app.SensationLeveldBKnob.Enable = "off";
                case 'On'
                    app.FreqModKnob.Enable = 'on';
                    app.TimeModKnob.Enable = 'on';
                    app.SensationLeveldBKnob.Enable = "on";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CalibrationFileMenu = uimenu(app.UIFigure);
            app.CalibrationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolClicked, true);
            app.CalibrationFileMenu.Text = 'Calibration File';
            app.PatientMenu = uimenu(app.UIFigure);
            app.PatientMenu.Text = 'Patient';
            app.LoadMenu = uimenu(app.PatientMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @GetCreatePatientID, true);
            app.LoadMenu.Text = 'Load';
            app.LoadMenu.Tag = 'LoadID';
            app.NewMenu = uimenu(app.PatientMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @GetCreatePatientID, true);
            app.NewMenu.Text = 'New';
            app.NewMenu.Tag = 'NewID';
            app.SoundCardMenu = uimenu(app.UIFigure);
            app.SoundCardMenu.Text = 'Sound Card';
            app.ASIOFirefaceUSBmenu = uimenu(app.SoundCardMenu);
            app.ASIOFirefaceUSBmenu.MenuSelectedFcn = createCallbackFcn(app, @SoundCardMenuSelected, true);
            app.ASIOFirefaceUSBmenu.Text = 'ASIO Fireface USB Ch.7+8';
            app.ASIOFirefaceUSBmenu.Tag = 'Fireface';
            app.ChooseSCMenu = uimenu(app.SoundCardMenu);
            app.ChooseSCMenu.MenuSelectedFcn = createCallbackFcn(app, @ChooseSCMenuSelected, true);
            app.ChooseSCMenu.Text = 'Choose SC';
            app.ChooseSCMenu.Tag = 'Default';
            app.ResetSCMenu = uimenu(app.SoundCardMenu);
            app.ResetSCMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetSCMenuSelected, true);
            app.ResetSCMenu.Text = 'Reset SC';
            app.ResetSCMenu.Tag = 'Reset';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SignalGeneratorMenu = uimenu(app.ToolsMenu);
            app.SignalGeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalGeneratorMenuSelected, true);
            app.SignalGeneratorMenu.Text = 'Signal Generator';
            app.DataViewerMenu = uimenu(app.ToolsMenu);
            app.DataViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @DataViewerMenuSelected, true);
            app.DataViewerMenu.Text = 'Data Viewer';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Trials')
            ylabel(app.UIAxes, 'dB SPL')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 50];
            app.UIAxes.YLim = [10 120];
            app.LFhearingLabel = uilabel(app.UIFigure);
            app.LFhearingLabel.Text = 'LF-hearing';
            app.HearingthresholdLabel = uilabel(app.UIFigure);
            app.HearingthresholdLabel.Text = 'Hearing threshold';
            app.SpectrotemporalsensitivityLabel = uilabel(app.UIFigure);
            app.SpectrotemporalsensitivityLabel.Text = 'Spectro-temporal sensitivity';
            app.ThresDataTab = uitable(app.UIFigure);
            app.ThresDataTab.ColumnName = {'Frequency'; 'Thres'; 'Ear'};
            app.ThresDataTab.RowName = {'Thres'; 'STM'};
            app.ThresDataTab.DisplayDataChangedFcn = createCallbackFcn(app, @UpdateTabsFromFileData, true);
            app.STMDataTab = uitable(app.UIFigure);
            app.STMDataTab.ColumnName = {'Frequency'; 'Cycles/Oct'; 'Cycles/Sec'; 'Sens Level'; 'Thres'; 'STD'; 'Ear'};
            app.STMDataTab.RowName = {};
            app.STMDataTab.DisplayDataChangedFcn = createCallbackFcn(app, @UpdateTabsFromFileData, true);
            app.PatinetInfoPanel = uipanel(app.UIFigure);
            app.PatientLabel = uilabel(app.PatinetInfoPanel);
            app.PatientLabel.Text = 'Patient: ';
            app.TypeLabel = uilabel(app.PatinetInfoPanel);
            app.TypeLabel.Text = 'Type:';
            app.AgeLabel = uilabel(app.PatinetInfoPanel);
            app.AgeLabel.Text = 'Age:';
            app.GenderLabel = uilabel(app.PatinetInfoPanel);
            app.GenderLabel.Text = 'Gender:';
            app.CurrentStimuliLabel = uilabel(app.UIFigure);
            app.CurrentStimuliLabel.Text = 'Current Stimuli';
            app.ThresParamPanel = uipanel(app.UIFigure);
            app.ThresParamPanel.Title = 'Threshold Frequencies [Hz]';
            app.f63 = uicheckbox(app.ThresParamPanel);
            app.f63.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f63.Text = '63';
            app.f80 = uicheckbox(app.ThresParamPanel);
            app.f80.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f80.Text = '80';
            app.f80.Value = true;
            app.f100 = uicheckbox(app.ThresParamPanel);
            app.f100.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f100.Text = '100';
            app.f100.Value = true;
            app.f125 = uicheckbox(app.ThresParamPanel);
            app.f125.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f125.Text = '125';
            app.f125.Value = true;
            app.f160 = uicheckbox(app.ThresParamPanel);
            app.f160.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f160.Text = '160';
            app.f200 = uicheckbox(app.ThresParamPanel);
            app.f200.ValueChangedFcn = createCallbackFcn(app, @ThresFrequencies, true);
            app.f200.Text = '200';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.STMparametersPanel = uipanel(app.UIFigure);
            app.STMparametersPanel.Title = 'STM parameters';
            app.GridLayout2 = uigridlayout(app.STMparametersPanel);
            app.FreetestmodeLabel = uilabel(app.GridLayout2);
            app.FreetestmodeLabel.Text = 'Free test mode:';
            app.FreetestmodeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.FreetestmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @FreetestmodeSwitchValueChanged, true);
            app.FreqModKnob = uiknob(app.GridLayout2, 'discrete');
            app.FreqModKnob.Items = {'2', '4', '8', '16', '24', '32', '64', '128'};
            app.FreqModKnob.ValueChangedFcn = createCallbackFcn(app, @FreqModKnobValueChanged, true);
            app.FreqModKnob.Value = '2';
            app.TimeModKnob = uiknob(app.GridLayout2, 'discrete');
            app.TimeModKnob.Items = {'2', '4', '8', '16', '24', '32', '64', '128'};
            app.TimeModKnob.ValueChangedFcn = createCallbackFcn(app, @FreqModKnobValueChanged, true);
            app.TimeModKnob.Value = '2';
            app.FreqModKnobLabel = uilabel(app.GridLayout2);
            app.FreqModKnobLabel.Text = 'Freq Mod';
            app.TimeModKnobLabel = uilabel(app.GridLayout2);
            app.TimeModKnobLabel.Text = 'Time Mod';
            app.SensationLeveldBKnob = uiknob(app.GridLayout2, 'continuous');
            app.SensationLeveldBKnob.Limits = [0 60];
            app.SensationLeveldBKnob.ValueChangedFcn = createCallbackFcn(app, @FreqModKnobValueChanged, true);
            app.SensationLeveldBKnob.MinorTicks = [-60 -59 -58 -57 -56 -55 -54 -53 -52 -51 -50 -49 -48 -47 -46 -45 -44 -43 -42 -41 -40 -39 -38 -37 -36 -35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0];
            app.SensationLeveldBKnob.Value = 40;
            app.CenterFrequencyKnob = uiknob(app.GridLayout2, 'discrete');
            app.CenterFrequencyKnob.Items = {'63', '80', '100', '125', '160', '200'};
            app.CenterFrequencyKnob.Value = '80';
            app.SensationLeveldBKnobLabel = uilabel(app.GridLayout2);
            app.SensationLeveldBKnobLabel.Text = 'Sensation Level [dB]';
            app.CenterFrequencyKnobLabel = uilabel(app.GridLayout2);
            app.CenterFrequencyKnobLabel.Text = 'Center Frequency';
            app.TestandEarPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.TestandEarPanel);
            app.EarSwitch = uiswitch(app.GridLayout3, 'slider');
            app.EarSwitch.Items = {'Left', 'Right'};
            app.EarSwitch.Value = 'Right';
            app.EarSwitchLabel = uilabel(app.GridLayout3);
            app.EarSwitchLabel.Text = 'Ear';
            app.TestTypeSwitchLabel = uilabel(app.GridLayout3);
            app.TestTypeSwitchLabel.Text = 'Test Type';
            app.TestTypeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.TestTypeSwitch.Items = {'Thres', 'STM'};
            app.TestTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TestTypeSwitchValueChanged, true);
            app.TestTypeSwitch.Value = 'Thres';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdAppMultiWin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end